classdef liver_CVPV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        Panel_3                      matlab.ui.container.Panel
        GridLayout12                 matlab.ui.container.GridLayout
        CompoundlistLabel            matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        CenterPanel                  matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        Liverveinidentificationconstructionof1DcoordinateLabel  matlab.ui.control.Label
        GridLayout11                 matlab.ui.container.GridLayout
        saveimageButton              matlab.ui.control.Button
        savedataButton               matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        BondcolorDropDown            matlab.ui.control.DropDown
        BondcolorDropDownLabel       matlab.ui.control.Label
        ColormapDropDown             matlab.ui.control.DropDown
        ColormapLabel                matlab.ui.control.Label
        onoffButton                  matlab.ui.control.StateButton
        LoadButton                   matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        MALDIbrowserTab              matlab.ui.container.Tab
        GridLayout5                  matlab.ui.container.GridLayout
        UIAxes_MALDI                 matlab.ui.control.UIAxes
        MarkersTab                   matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        Panel_2                      matlab.ui.container.Panel
        GridLayout9                  matlab.ui.container.GridLayout
        bonds0Label                  matlab.ui.control.Label
        RunButton                    matlab.ui.control.Button
        bondlengthmaxEditField       matlab.ui.control.NumericEditField
        bondlengthmaxEditFieldLabel  matlab.ui.control.Label
        bondlengthminEditField       matlab.ui.control.NumericEditField
        bondlengthminEditFieldLabel  matlab.ui.control.Label
        updateButton                 matlab.ui.control.Button
        threshold2EditField          matlab.ui.control.NumericEditField
        threshold2EditFieldLabel     matlab.ui.control.Label
        threshold3EditField          matlab.ui.control.NumericEditField
        threshold3EditFieldLabel     matlab.ui.control.Label
        threshold1EditField          matlab.ui.control.NumericEditField
        threshold1EditFieldLabel     matlab.ui.control.Label
        TICnormalizeCheckBox         matlab.ui.control.CheckBox
        vein_minEditField            matlab.ui.control.NumericEditField
        vein_minEditFieldLabel       matlab.ui.control.Label
        sigmaEditField               matlab.ui.control.NumericEditField
        sigmaEditFieldLabel          matlab.ui.control.Label
        UIAxesHist3                  matlab.ui.control.UIAxes
        UIAxesHist2                  matlab.ui.control.UIAxes
        UIAxesHist1                  matlab.ui.control.UIAxes
        UIAxes_veins                 matlab.ui.control.UIAxes
        UIAxes_marker                matlab.ui.control.UIAxes
        CV_PVTab                     matlab.ui.container.Tab
        GridLayout6                  matlab.ui.container.GridLayout
        UIAxes_CV_PV_mean            matlab.ui.control.UIAxes
        UIAxes_MALDI2                matlab.ui.control.UIAxes
        UIAxes_CV_PV_select          matlab.ui.control.UIAxes
        exportTab                    matlab.ui.container.Tab
        GridLayout7                  matlab.ui.container.GridLayout
        exportPanel                  matlab.ui.container.Panel
        UIAxes_classification        matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        colorscaleSlider             matlab.ui.control.Slider
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        imax % Description
        pks
        pk
        cv
        pv
        bonds
        bond
        flag_pv
        I_veins
        I_marker
        fnames
        metadata
        TIC
        sigma


    end
    
    properties (Access = public)
        lineobj1
        lineobj2 
        lineobj3 
        circleobj1
        circleobj2
    end
    
    methods (Access = private)


        function imshow_MALDI(app)
            eval(['cmap=',app.ColormapDropDown.Value,';']);
            scale=app.colorscaleSlider.Value;
            item = app.UITable.Selection(1);
            [imgdata,alpha]=idata2imgdata(app.imax(:,item+3),app.metadata);
            RGB=msi2rgb(imgdata, alpha, cmap, [0,scale],[.93,.93,.93]);
            imshow(RGB,'Parent',app.UIAxes_MALDI)
            imshow(RGB,'Parent',app.UIAxes_MALDI2)
            app.UIAxes_MALDI.Title.String=app.pks(item).Name;
            app.UIAxes_MALDI2.Title.String=app.pks(item).Name;
            imshow_bonds(app)
        end
        
        function imshow_marker(app)
            I0=idata2imgdata(app.imax(:,0+3),app.metadata)/mean(app.TIC); %get TIC
            I1=idata2imgdata(app.imax(:,4),app.metadata); % get marker images
            I2=idata2imgdata(app.imax(:,5),app.metadata);
            I3=idata2imgdata(app.imax(:,6),app.metadata);

            if app.TICnormalizeCheckBox.Value
                I1=I1./I0;
                I2=I2./I0;
                I3=I3./I0;
            end

            I1 = imgaussfilt(I1,app.sigmaEditField.Value);
            I2 = imgaussfilt(I2,app.sigmaEditField.Value);
            I3 = imgaussfilt(I3,app.sigmaEditField.Value);

            I1=log10(I1+eps);
            I1=I1/max(max(I1));
            I1=I1-mean(mean(I1,'omitnan'),'omitnan')+0.67; %recenter
            I1(I1<0)=0;
            
            
            I2=log10(I2+eps);
            I2=I2/max(max(I2));
            I2=I2-mean(mean(I2,'omitnan'),'omitnan')+0.65; %recenter
            I2(I2<0)=0;
           
            
            I3=log10(I3+eps);
            I3=I3/max(max(I3));
            I3=I3-mean(mean(I3,'omitnan'),'omitnan')+0.93; %recenter
            I3(I3<0)=0;
            
            app.I_marker{1}=I1;
            app.I_marker{2}=I2;
            app.I_marker{3}=I3;
            thred1=app.threshold1EditField.Value;
            thred2=app.threshold2EditField.Value;
            thred3=app.threshold3EditField.Value;
            BW1 = imbinarize(I1,thred1);
            BW2 = imbinarize(I2,thred2);
            BW3 = imbinarize(I3,thred3);

            imgC=[];
            imgC(:,:,1)=floor(I2*256);
            imgC(:,:,2)=floor(I1*256);
            imgC(:,:,3)=floor(I3*256);
            imgC(:,:,1)=I2-0.5;
            imgC(:,:,2)=I1-0.5;
            imgC(:,:,3)=I3-0.85;

            veins_xy=[];app.flag_pv=[];
            veins_img=zeros(size(BW1));
            %find all vein centers
            s2 = regionprops(BW2,I2,'all');
            vein_size_min=app.vein_minEditField.Value;
            s2=s2([s2.Area]>=vein_size_min);
            M=load('md_net.mat');
            md_net=M.md_net;
            confid=0.5;
            for i=1:length(s2)
                veins_xy(i,:)=s2(i).Centroid;
                pixels=s2(i).PixelList;
                bd=s2(i).BoundingBox;
                [app.I_veins{i},na]=get_I_veins(veins_xy(i,:),11,I1,I2,I3);                
            %--------------------------trained classifier for CV & PV
              [flag,scores]=classify(md_net,im2uint8(app.I_veins{i}));
              if flag=='PV' && scores(2)>confid
                 app.flag_pv(i)=1; %PV
              elseif flag=='CV' && scores(1)>confid 
                 app.flag_pv(i)=0; %CV
              else
                 app.flag_pv(i)=0.5;  %not sure 
              end
            %----------------------------
                for j=1:size(pixels,1)
                    veins_img(pixels(j,2),pixels(j,1))=app.flag_pv(i)*2-1;
                end
            end
            veins_idata=[];
            for i=1:size(app.imax,1)
                veins_idata(i,1)=veins_img(app.metadata(i,2),app.metadata(i,1));
            end
            
            [I4,alpha] = idata2imgdata(veins_idata,app.metadata);
            I4a = msi2rgb(I4,alpha);
            app.cv=veins_xy(app.flag_pv==0,:); cvid=find(app.flag_pv==0);
            app.pv=veins_xy(app.flag_pv==1,:); pvid=find(app.flag_pv==1);

            imshow(imgC*2,'Parent',app.UIAxes_marker)
            imshow(BW2,'Parent',app.UIAxes_veins)            
            imshow(I4,'Parent',app.UIAxes_veins)
            app.circleobj1=viscircles(app.UIAxes_veins,app.cv, 1,'Color','r'); %highlight veins
            app.circleobj2=viscircles(app.UIAxes_veins,app.pv, 1,'Color','g');
            liv_show_classification(app.UIAxes_classification, app.I_veins,app.flag_pv);

  % creates bonds
             n=0;app.bonds=[]; 
            bondmin=app.bondlengthminEditField.Value;
            bondmax=app.bondlengthmaxEditField.Value;
            for i=1:size(app.cv,1)
                for j=1:size(app.pv,1)
                     dist=((app.pv(j,1)-app.cv(i,1))^2+(app.pv(j,2)-app.cv(i,2))^2)^0.5;
                    if dist>=bondmin && dist<=bondmax
                       n=n+1;
                       app.bonds(n).node1=app.cv(i,:);
                       app.bonds(n).node2=app.pv(j,:);
                       app.bonds(n).dist=dist;
                    end
                end
            end
            
            %  draw bonds
            imshow_bonds(app);

        end
        
        function results = onLineClick(app,src,event)
            for i=1:length(app.bonds)
                app.lineobj3{i}.LineWidth=0.5;
            end            
            src.LineWidth=2.5; 
             if isfield(app.pks,'sig')
              liv_plot_cv_pv_single(app.UIAxes_CV_PV_select,app.pk);
              liv_plot_cv_pv_single_bond(app.UIAxes_CV_PV_select,app.pk,src.UserData);
             end

        end
        
        function imshow_bonds(app)
            app.lineobj1=[];
            app.lineobj2=[];
            app.lineobj3=[];
            for i=1:length(app.bonds)
               app.lineobj1{i}=line(app.UIAxes_marker,[app.bonds(i).node1(1),app.bonds(i).node2(1)],[app.bonds(i).node1(2),app.bonds(i).node2(2)],'color',app.BondcolorDropDown.Value);
               app.lineobj2{i}=line(app.UIAxes_veins,[app.bonds(i).node1(1),app.bonds(i).node2(1)],[app.bonds(i).node1(2),app.bonds(i).node2(2)],'color',app.BondcolorDropDown.Value);
               app.lineobj3{i}=line(app.UIAxes_MALDI2,[app.bonds(i).node1(1),app.bonds(i).node2(1)],[app.bonds(i).node1(2),app.bonds(i).node2(2)],'color',app.BondcolorDropDown.Value);
               app.lineobj3{i}.ButtonDownFcn=@(src, event)onLineClick(app,src, event);
               app.lineobj3{i}.UserData=i;
               if app.onoffButton.Value
                app.lineobj1{i}.Visible='on';
                app.lineobj2{i}.Visible='on';
                app.lineobj3{i}.Visible='on';
               else
                   app.lineobj1{i}.Visible='off';
                   app.lineobj2{i}.Visible='off';
                   app.lineobj3{i}.Visible='off';
               end
            end
            app.bonds0Label.Text=['#bonds:',num2str(length(app.bonds))];       
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pks_tb=readtable('peaklist.xlsx');
            app.pks=table2struct(readtable('peaklist.xlsx'));
            app.UITable.Data=pks_tb;
            app.UITable.ColumnName=pks_tb.Properties.VariableNames;
            app.UITable.Selection=[1,1];
%             app.UIFigure.Units = 'normalized';
%             app.UIFigure.Position = [0.05, 0.05,0.9,0.9];
           % app.UIFigure.Units = "pixels";
                   app.UIFigure.WindowState = 'maximized';
            %app.LoadButtonPushed(app);
%             fn = dir('*.csv');
%             app.liverdataDropDown.Items={fn.name};
%             app.liverdataDropDown.ItemsData=1:length({fn.name});
%             app.fnames = fullfile({fn.folder},{fn.name});
%             liverdataDropDownValueChanged(app)   
%             updateButtonPushed(app)
            
            
        end

        % Callback function: TICnormalizeCheckBox, bondlengthmaxEditField, 
        % ...and 7 other components
        function updateButtonPushed(app, event)
            imshow_marker(app);
            histogram(app.UIAxesHist1,app.I_marker{1},100)
            hold (app.UIAxesHist1,'on');
            yl=ylim(app.UIAxesHist1);
            plot(app.UIAxesHist1,[app.threshold1EditField.Value,app.threshold1EditField.Value],[yl(1),yl(2)],'--r');
            hold (app.UIAxesHist1,'on');
            plot(app.UIAxesHist1,[0.5,0.5],[yl(1),yl(2)],'--g');
            xlim(app.UIAxesHist1,[0,1]);
            hold (app.UIAxesHist1,'off');

            histogram(app.UIAxesHist2,app.I_marker{2},100)
            hold (app.UIAxesHist2,'on');
            yl=ylim(app.UIAxesHist2);
            plot(app.UIAxesHist2,[app.threshold2EditField.Value,app.threshold2EditField.Value],[yl(1),yl(2)],'--r');
            hold (app.UIAxesHist2,'on');
            plot(app.UIAxesHist2,[0.5,0.5],[yl(1),yl(2)],'--g');
            hold (app.UIAxesHist2,'off');
            xlim(app.UIAxesHist2,[0,1]);

            histogram(app.UIAxesHist3,app.I_marker{3},100)
            hold (app.UIAxesHist3,'on');
            yl=ylim(app.UIAxesHist3);
            plot(app.UIAxesHist3,[app.threshold3EditField.Value,app.threshold3EditField.Value],[yl(1),yl(2)],'--r');
            hold (app.UIAxesHist3,'on');
            plot(app.UIAxesHist3,[0.85,0.85],[yl(1),yl(2)],'--g');
            hold (app.UIAxesHist3,'off');  
            xlim(app.UIAxesHist3,[0.6,1]);
            
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            app.pk=app.pks(selection(1));
            imshow_MALDI(app);
            cla(app.UIAxes_CV_PV_select);
            if isfield(app.pks,'sig')
              liv_plot_cv_pv_single(app.UIAxes_CV_PV_mean,app.pk);             
              hold(app.UIAxes_CV_PV_mean,'off')
            else
                cla(app.UIAxes_CV_PV_select)
            end
        end

        % Value changed function: onoffButton
        function onoffButtonValueChanged(app, event)
            value = app.onoffButton.Value;
            for i=1:length(app.bonds)
               if value
                app.lineobj1{i}.Visible='on';
                app.lineobj2{i}.Visible='on';
                app.lineobj3{i}.Visible='on';
               else
                   app.lineobj1{i}.Visible='off';
                   app.lineobj2{i}.Visible='off';
                   app.lineobj3{i}.Visible='off';
               end
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.MarkersTab;
            [app.bonds,app.pks]=liv_readsig(app,app.bonds,app.imax,50,[.2,.8],app.pks);
           
        end

        % Value changed function: ColormapDropDown, colorscaleSlider
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            imshow_MALDI(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            f = figure('Render', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure          
            app.pks=table2struct(readtable('peaklist.xlsx'));
            [file,path]=uigetfile('*.csv');
            delete(f);
            fn=fullfile(path,file);
            app.Label.Text=fn;
            app.imax = readmatrix(fn); 
            app.metadata = app.imax(:,1:2);
            app.TIC = app.imax(:,3);
            app.sigma = app.sigmaEditField.Value;
            cla(app.UIAxes_MALDI);
            cla(app.UIAxes_MALDI2);
            cla(app.UIAxes_veins);
            app.bonds=[];
            imshow_MALDI(app)
            drawnow();
            imshow_marker(app);
            imshow_bonds(app);
            updateButtonPushed(app)
        end

        % Value changed function: BondcolorDropDown
        function BondcolorDropDownValueChanged(app, event)
            value = app.BondcolorDropDown.Value;
            for i=1:length(app.bonds)
                app.lineobj1{i}.Color=value;
                app.lineobj2{i}.Color=value;
                app.lineobj3{i}.Color=value;
            end
        end

        % Button pushed function: savedataButton
        function savedataButtonPushed(app, event)
            pks=app.pks;
            bonds=app.bonds;
            save('output.mat','bonds','pks','-mat');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {768, 768, 768};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {768, 768};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {243, '1x', 73};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout8 = uigridlayout(app.LeftPanel);
            app.UITable = uitable(app.GridLayout8);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.Panel_3 = uipanel(app.GridLayout8);
            app.GridLayout12 = uigridlayout(app.Panel_3);
            app.CompoundlistLabel = uilabel(app.GridLayout12);
            app.CompoundlistLabel.Text = 'Compound list';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.MALDIbrowserTab = uitab(app.TabGroup);
            app.MALDIbrowserTab.Title = 'MALDI browser';
            app.GridLayout5 = uigridlayout(app.MALDIbrowserTab);
            app.UIAxes_MALDI = uiaxes(app.GridLayout5);
            app.UIAxes_MALDI.XTick = [];
            app.UIAxes_MALDI.YTick = [];
            app.MarkersTab = uitab(app.TabGroup);
            app.MarkersTab.Title = 'Markers';
            app.GridLayout4 = uigridlayout(app.MarkersTab);
            app.UIAxes_marker = uiaxes(app.GridLayout4);
            app.UIAxes_marker.XTick = [];
            app.UIAxes_marker.YTick = [];
            app.UIAxes_veins = uiaxes(app.GridLayout4);
            app.UIAxes_veins.XTick = [];
            app.UIAxes_veins.XTickLabel = '';
            app.UIAxes_veins.YTick = [];
            app.UIAxes_veins.YTickLabel = '';
            app.UIAxesHist1 = uiaxes(app.GridLayout4);
            title(app.UIAxesHist1, 'Marker 1')
            app.UIAxesHist1.YTick = [];
            app.UIAxesHist2 = uiaxes(app.GridLayout4);
            title(app.UIAxesHist2, 'Marker 2')
            app.UIAxesHist2.YTick = [];
            app.UIAxesHist2.YTickLabel = '';
            app.UIAxesHist3 = uiaxes(app.GridLayout4);
            title(app.UIAxesHist3, 'Marker 3')
            app.UIAxesHist3.YTick = [];
            app.UIAxesHist3.YTickLabel = '';
            app.sigmaEditFieldLabel = uilabel(app.GridLayout4);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.GridLayout4, 'numeric');
            app.sigmaEditField.Limits = [0.5 5];
            app.sigmaEditField.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.sigmaEditField.Value = 1;
            app.vein_minEditFieldLabel = uilabel(app.GridLayout4);
            app.vein_minEditFieldLabel.Text = 'vein_min';
            app.vein_minEditField = uieditfield(app.GridLayout4, 'numeric');
            app.vein_minEditField.Limits = [1 10];
            app.vein_minEditField.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.vein_minEditField.Value = 3;
            app.TICnormalizeCheckBox = uicheckbox(app.GridLayout4);
            app.TICnormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.TICnormalizeCheckBox.Text = 'TIC normalize';
            app.threshold1EditFieldLabel = uilabel(app.GridLayout4);
            app.threshold1EditFieldLabel.Text = 'threshold 1';
            app.threshold1EditField = uieditfield(app.GridLayout4, 'numeric');
            app.threshold1EditField.Limits = [0.51 1];
            app.threshold1EditField.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.threshold1EditField.Value = 0.75;
            app.threshold3EditFieldLabel = uilabel(app.GridLayout4);
            app.threshold3EditFieldLabel.Text = 'threshold 3';
            app.threshold3EditField = uieditfield(app.GridLayout4, 'numeric');
            app.threshold3EditField.Limits = [0.851 1];
            app.threshold3EditField.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.threshold3EditField.Value = 0.96;
            app.threshold2EditFieldLabel = uilabel(app.GridLayout4);
            app.threshold2EditFieldLabel.Text = 'threshold 2';
            app.threshold2EditField = uieditfield(app.GridLayout4, 'numeric');
            app.threshold2EditField.Limits = [0.51 1];
            app.threshold2EditField.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.threshold2EditField.Value = 0.8;
            app.Panel_2 = uipanel(app.GridLayout4);
            app.GridLayout9 = uigridlayout(app.Panel_2);
            app.updateButton = uibutton(app.GridLayout9, 'push');
            app.updateButton.ButtonPushedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.updateButton.Text = 'update';
            app.bondlengthminEditFieldLabel = uilabel(app.GridLayout9);
            app.bondlengthminEditFieldLabel.Text = 'bond length(min)';
            app.bondlengthminEditField = uieditfield(app.GridLayout9, 'numeric');
            app.bondlengthminEditField.Limits = [5 50];
            app.bondlengthminEditField.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.bondlengthminEditField.Value = 20;
            app.bondlengthmaxEditFieldLabel = uilabel(app.GridLayout9);
            app.bondlengthmaxEditFieldLabel.Text = 'bond length(max)';
            app.bondlengthmaxEditField = uieditfield(app.GridLayout9, 'numeric');
            app.bondlengthmaxEditField.Limits = [5 150];
            app.bondlengthmaxEditField.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.bondlengthmaxEditField.Value = 45;
            app.RunButton = uibutton(app.GridLayout9, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.bonds0Label = uilabel(app.GridLayout9);
            app.bonds0Label.Text = '#bonds=0';
            app.CV_PVTab = uitab(app.TabGroup);
            app.CV_PVTab.Title = 'CV_PV';
            app.GridLayout6 = uigridlayout(app.CV_PVTab);
            app.UIAxes_CV_PV_select = uiaxes(app.GridLayout6);
            app.UIAxes_CV_PV_select.XTick = [];
            app.UIAxes_CV_PV_select.YTick = [];
            app.UIAxes_MALDI2 = uiaxes(app.GridLayout6);
            title(app.UIAxes_MALDI2, 'Compound')
            zlabel(app.UIAxes_MALDI2, 'Z')
            app.UIAxes_CV_PV_mean = uiaxes(app.GridLayout6);
            app.UIAxes_CV_PV_mean.XTick = [];
            app.UIAxes_CV_PV_mean.YTick = [];
            app.exportTab = uitab(app.TabGroup);
            app.exportTab.Title = 'export';
            app.GridLayout7 = uigridlayout(app.exportTab);
            app.UIAxes_classification = uiaxes(app.GridLayout7);
            title(app.UIAxes_classification, 'left = PV, right =CV')
            xlabel(app.UIAxes_classification, 'X')
            ylabel(app.UIAxes_classification, 'Y')
            zlabel(app.UIAxes_classification, 'Z')
            app.exportPanel = uipanel(app.GridLayout7);
            app.exportPanel.Title = 'export';
            app.Panel = uipanel(app.GridLayout3);
            app.GridLayout11 = uigridlayout(app.GridLayout3);
            app.LoadButton = uibutton(app.GridLayout11, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.onoffButton = uibutton(app.GridLayout11, 'state');
            app.onoffButton.ValueChangedFcn = createCallbackFcn(app, @onoffButtonValueChanged, true);
            app.onoffButton.Text = 'on/off';
            app.onoffButton.Value = true;
            app.ColormapLabel = uilabel(app.GridLayout11);
            app.ColormapLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.GridLayout11);
            app.ColormapDropDown.Items = {'gray', 'jet', 'parula', 'hsv', 'cool', 'spring', 'summer', 'winter', 'autumn', 'hot', 'copper'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'jet';
            app.BondcolorDropDownLabel = uilabel(app.GridLayout11);
            app.BondcolorDropDownLabel.Text = 'Bond color';
            app.BondcolorDropDown = uidropdown(app.GridLayout11);
            app.BondcolorDropDown.Items = {'red', 'blue', 'green', 'magenta', 'cyan', 'yellow', 'write', 'black'};
            app.BondcolorDropDown.ItemsData = {'r', 'b', 'g', 'm', 'c', 'y', 'w', 'k'};
            app.BondcolorDropDown.ValueChangedFcn = createCallbackFcn(app, @BondcolorDropDownValueChanged, true);
            app.BondcolorDropDown.Value = 'y';
            app.Label = uilabel(app.GridLayout11);
            app.Label.Text = '';
            app.savedataButton = uibutton(app.GridLayout11, 'push');
            app.savedataButton.ButtonPushedFcn = createCallbackFcn(app, @savedataButtonPushed, true);
            app.savedataButton.Text = 'save data';
            app.saveimageButton = uibutton(app.GridLayout11, 'push');
            app.saveimageButton.Text = 'save image';
            app.Liverveinidentificationconstructionof1DcoordinateLabel = uilabel(app.GridLayout3);
            app.Liverveinidentificationconstructionof1DcoordinateLabel.Text = 'Liver vein identification & construction of 1D coordinate  ';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout10 = uigridlayout(app.RightPanel);
            app.colorscaleSlider = uislider(app.GridLayout10);
            app.colorscaleSlider.Limits = [0 1];
            app.colorscaleSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.colorscaleSlider.MajorTickLabels = {'0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.colorscaleSlider.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.colorscaleSlider.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = liver_CVPV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end